classdef Coin_Counter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        btnBrowse            matlab.ui.control.Button
        ShowmethemoneyLabel  matlab.ui.control.Label
        editFilepath         matlab.ui.control.EditField
        imgCoins             matlab.ui.control.Image
        btnRunProgram        matlab.ui.control.Button
        CoinCounterLabel     matlab.ui.control.Label
        CreatedbyHarryGordensternandJoshMalnatiLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnBrowse
        function browse_for_file(app, event)
            [file, path] = uigetfile(["*.png; *.jpg; *.jpeg; *.gif; *.svg", ...
                                    "Image Files (*.png; *.jpg; *.jpeg; *.gif; *.svg)";]);
            app.editFilepath.Value = strcat(path, file);
            app.imgCoins.ImageSource = app.editFilepath.Value;
            app.imgCoins.Visible = true;
        end

        % Button pushed function: btnRunProgram
        function run_program(app, event)
            Coin_Counter
            
        end

        % Value changed function: editFilepath
        function find_filepath(app, event)
            value = app.editFilepath.Value;
            app.imgCoins.ImageSource = value;
            app.imgCoins.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnBrowse = uibutton(app.UIFigure, 'push');
            app.btnBrowse.ButtonPushedFcn = createCallbackFcn(app, @browse_for_file, true);
            app.btnBrowse.Text = 'Browse';
            app.ShowmethemoneyLabel = uilabel(app.UIFigure);
            app.ShowmethemoneyLabel.Text = 'Show me the money!';
            app.editFilepath = uieditfield(app.UIFigure, 'text');
            app.editFilepath.ValueChangedFcn = createCallbackFcn(app, @find_filepath, true);
            app.imgCoins = uiimage(app.UIFigure);
            app.imgCoins.Visible = 'off';
            app.btnRunProgram = uibutton(app.UIFigure, 'push');
            app.btnRunProgram.ButtonPushedFcn = createCallbackFcn(app, @run_program, true);
            app.btnRunProgram.Text = 'Run Program';
            app.CoinCounterLabel = uilabel(app.UIFigure);
            app.CoinCounterLabel.Text = 'Coin Counter';
            app.CreatedbyHarryGordensternandJoshMalnatiLabel = uilabel(app.UIFigure);
            app.CreatedbyHarryGordensternandJoshMalnatiLabel.Text = 'Created by Harry Gordenstern and Josh Malnati';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coin_Counter_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end